classdef HW2_M11112024 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        FileMenu        matlab.ui.container.Menu
        OpenMenu        matlab.ui.container.Menu
        SaveAsMenu      matlab.ui.container.Menu
        ExitMenu        matlab.ui.container.Menu
        ProcessMenu     matlab.ui.container.Menu
        NegativeMenu    matlab.ui.container.Menu
        LRFlipMenu      matlab.ui.container.Menu
        DarkerMenu      matlab.ui.container.Menu
        DifferenceMenu  matlab.ui.container.Menu
        GrayScaleMenu   matlab.ui.container.Menu
        SmoothMenu      matlab.ui.container.Menu
        SharperMenu     matlab.ui.container.Menu
        WatermarkMenu   matlab.ui.container.Menu
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        DeleteButton    matlab.ui.control.StateButton
        Slider          matlab.ui.control.Slider
        Button          matlab.ui.control.StateButton
        Label           matlab.ui.control.Label
        Image           matlab.ui.control.Image
        RightPanel      matlab.ui.container.Panel
        Label2          matlab.ui.control.Label
        Image_2         matlab.ui.control.Image
        Button_2        matlab.ui.control.StateButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        vidObj %視訊物件
        im %當下的畫面
        im_prev %前一幀的畫面
        p = 1 %影像處理選項
        watermark_str = "m11112024"    % 浮水印字串
        deleteBtn_helper  % 刪除影片選項
        delete_list = []  % 紀錄要刪除的範圍
    end
    
    methods (Access = private)
        
        function results = func(app) %影像處理
            position = [300 300];
            switch app.p %選項
                case 1 %反白
                    results = 255- app.im;
                    if app.deleteBtn_helper      % 若 deleteBtn_helper == 1 則代表要刪除影片 
                        results = results - 70;  % 將影片變暗
                        results = insertText(results,position,'Delete',FontSize = 20,BoxColor="blue",TextColor='white');  % 插入字串
                    end
                    app.Label2.Text = 'Negative';
                case 2 %左右翻轉
                    results = fliplr(app.im);
                    if app.deleteBtn_helper
                        results = results - 70;
                        results = insertText(results,position,'Delete',FontSize = 20,BoxColor="blue",TextColor='white');
                    end
                    app.Label2.Text = 'LR Flip';
                case 3 %變暗
                    results = app.im - 64;
                    if app.deleteBtn_helper
                        results = results - 70;
                        results = insertText(results,position,'Delete',FontSize = 20,BoxColor="blue",TextColor='white');
                    end
                    app.Label2.Text = 'Darker';
                case 4 %影像時間差異
                    results = imabsdiff(app.im,app.im_prev); %跟前畫面的差異
                    if app.deleteBtn_helper
                        results = results - 70;
                        results = insertText(results,position,'Delete',FontSize = 20,BoxColor="blue",TextColor='white');
                    end
                    app.im_prev = app.im;
                    app.Label2.Text = 'Temporal Difference';
                case 5 % 轉灰階
                    g = 0.2126*app.im(:,:,1) + 0.7152*app.im(:,:,2) + 0.0722*app.im(:,:,3);
                    g = uint8(g);               % 將浮點數型態的g轉換成8-bit正整數型態
                    results = cat(3, g, g, g);  % 在第3維度上重疊三個g
                    if app.deleteBtn_helper
                        results = results - 70;
                        results = insertText(results,position,'Delete',FontSize = 20,BoxColor="blue",TextColor='white');
                    end
                    app.Label2.Text = 'Gray Scale';
                case 6 % 高斯擬和 平滑化 
                    results = imgaussfilt(app.im,4);
                    if app.deleteBtn_helper
                        results = results - 70;
                        results = insertText(results,position,'Delete',FontSize = 20,BoxColor="blue",TextColor='white');
                    end
                    app.Label2.Text = 'Smooth'; 
                case 7 % 銳利化
                    w = fspecial('laplacian',0);
                    results_2 = imfilter(app.im,w,"replicate");
                    results = app.im - results_2;
                    if app.deleteBtn_helper
                        results = results - 70;
                        results = insertText(results,position,'Delete',FontSize = 20,BoxColor="blue",TextColor='white');
                    end
                    app.Label2.Text = 'Sharper';

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.Image.ScaleMethod = 'stretch';
            app.Image_2.ScaleMethod = 'stretch';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {538, 538};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {547, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            %讀取視訊路徑檔名
            [filename, pathname]  = uigetfile({'*.mp4';'*.avi';'*.mpg'},'read a video file'); 
            %建立是視訊物件
            app.vidObj = VideoReader([pathname filename]);
            app.im = read(app.vidObj, 1); %讀取第一幀的影像
            app.Image.ImageSource = app.im; %顯示到 Image 圖框
            app.im_prev = app.im; %暫存(用於比較影像差異)
            app.Image_2.ImageSource = func(app); 
            app.Button.Icon = 'down.jpg';
            app.Label.Text = '0';
            app.Slider.Limits = [0 app.vidObj.Duration + 0.0001];        % 設定 slider 長度
            figure(app.UIFigure)
        end

        % Callback function: ExitMenu, UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Menu selected function: NegativeMenu
        function NegativeMenuSelected(app, event)
            app.p = 1;
            app.Image_2.ImageSource = func(app);
        end

        % Menu selected function: LRFlipMenu
        function LRFlipMenuSelected(app, event)
            app.p = 2;
            app.Image_2.ImageSource = func(app);
        end

        % Menu selected function: DarkerMenu
        function DarkerMenuSelected(app, event)
            app.p = 3;
            app.Image_2.ImageSource = func(app);
        end

        % Callback function
        function ButtonPushed(app, event)
%             read(app.vidObj,1);
%             app.Button.Icon = 'pause.jpg';
% 
%             while hasFrame(app.vidObj)
%                 app.im = readFrame(app.vidObj);
%                 pause(1/app.vidObj.FrameRate)
%                 app.Label.Text = num2str(app.vidObj.CurrentTime,'%.2f');
%                 app.Image.ImageSource = app.im;
%                 app.Image_2.ImageSource = func(app);
%             end
%             app.Button.Icon = 'down.jpg';
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            if value
                app.Button.Icon = 'pause.jpg';
                read(app.vidObj,1);
                app.vidObj.CurrentTime = str2double(app.Label.Text());
                while hasFrame(app.vidObj)
                    if app.Button.Value == 0
                        break;
                    end
                    app.im = readFrame(app.vidObj);
                    pause(1/app.vidObj.FrameRate)
                    app.Label.Text = num2str(app.vidObj.CurrentTime,'%.2f');
                    app.Image.ImageSource = app.im;
                    app.Image_2.ImageSource = func(app);
                    app.SliderValueChanged()
                end
                a = length(app.delete_list);          % a = list 的長度
                a = mod(a,2);                         % 對 a 取 2 的餘數
                if (a ~= 0)                           % 若 a 為奇數則在 list 後面增加最後的時間
                    app.delete_list(end + 1) = app.vidObj.Duration;
                end
                fprintf('start\t\t end\n');         % 在 command window 輸出資料
                fprintf("%f %f\n",app.delete_list);
            else
                app.Button.Icon = 'down.jpg';
            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            [filename, pathname] = uiputfile({'*.mp4'},'Save a video file');
            vidObj2 = VideoWriter([pathname filename],'MPEG-4'); %建立待寫入的mp4視訊物件
            vidObj2.Quality = 90; % default quality = 75
            open(vidObj2) %開啟視訊物件
            read(app.vidObj,1); %讀第一幀
            position = [30 30];
            i = 1;     
            while hasFrame(app.vidObj) %如果還有畫面，就寫入影像
                video_time = round(app.vidObj.CurrentTime,2);
                app.im = readFrame(app.vidObj); %讀取下一幀
                im2 = func(app);
                im3 = insertText(im2,position,app.watermark_str,FontSize = 12,BoxColor="blue",TextColor='white'); % 將 im2 加入浮水印 轉成 im3
                if(i <= length(app.delete_list) && app.delete_list(i) == video_time)  % 若 i <= delete_list 的長度且 delete_list的值等於影片時間
                    i = i + 1;
                end    
                a = mod(i,2);   % 對 a 做2的餘數 
                if(a == 0)      % 若 a 等於 0 則不存入影片 
                    continue
                end
                writeVideo(vidObj2, im3); %將這一幀寫入 vidObj2 視訊
            end
            close(vidObj2); %關閉 vidObj2 視訊物件        
        end

        % Menu selected function: DifferenceMenu
        function DifferenceMenuSelected(app, event)
            app.p = 4;
            app.Image_2.ImageSource = func(app);
        end

        % Menu selected function: WatermarkMenu
        function WatermarkMenuSelected(app, event)
            dlgtitle = 'Watermark';     % 設定對話窗字元
            prompt = {'Input student ID'};
            dims = [1 50];              % 範圍
            defiput = {'M11112024'};    % 預設字串
            app.watermark_str = inputdlg(prompt,dlgtitle,dims,defiput);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Slider.Value = app.vidObj.CurrentTime;   % 讓 Slider 值跟著 Video 時間移動
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.vidObj.CurrentTime = changingValue;     % 透過移動 Slider 改變 Video 時間 
        end

        % Menu selected function: GrayScaleMenu
        function GrayScaleMenuSelected(app, event)
            app.p = 5;
            app.Image_2.ImageSource = func(app);
        end

        % Menu selected function: SmoothMenu
        function SmoothMenuSelected(app, event)
            app.p = 6;
            app.Image_2.ImageSource = func(app);
        end

        % Menu selected function: SharperMenu
        function SharperMenuSelected(app, event)
            app.p = 7;
            app.Image_2.ImageSource = func(app);
        end

        % Value changed function: DeleteButton
        function DeleteButtonValueChanged(app, event)
            value = app.DeleteButton.Value;
            if value
                app.Button_2.BackgroundColor = [0.5 0.5 0.5];      % 讓 delete_btn 變暗 
                app.delete_list(end + 1) = [str2double(app.Label.Text)];
                app.deleteBtn_helper = 1;
            else
                app.Button_2.BackgroundColor = [0.96 0.96 0.96];   % 讓 delete_btn 復原
                app.delete_list(end + 1) = [str2double(app.Label.Text)];
                app.deleteBtn_helper = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.ProcessMenu = uimenu(app.UIFigure);
            app.ProcessMenu.Text = 'Process';
            app.NegativeMenu = uimenu(app.ProcessMenu);
            app.NegativeMenu.MenuSelectedFcn = createCallbackFcn(app, @NegativeMenuSelected, true);
            app.NegativeMenu.Text = 'Negative';
            app.LRFlipMenu = uimenu(app.ProcessMenu);
            app.LRFlipMenu.MenuSelectedFcn = createCallbackFcn(app, @LRFlipMenuSelected, true);
            app.LRFlipMenu.Text = 'LR Flip';
            app.DarkerMenu = uimenu(app.ProcessMenu);
            app.DarkerMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkerMenuSelected, true);
            app.DarkerMenu.Text = 'Darker';
            app.DifferenceMenu = uimenu(app.ProcessMenu);
            app.DifferenceMenu.MenuSelectedFcn = createCallbackFcn(app, @DifferenceMenuSelected, true);
            app.DifferenceMenu.Text = 'Difference';
            app.GrayScaleMenu = uimenu(app.ProcessMenu);
            app.GrayScaleMenu.MenuSelectedFcn = createCallbackFcn(app, @GrayScaleMenuSelected, true);
            app.GrayScaleMenu.Text = 'Gray Scale';
            app.SmoothMenu = uimenu(app.ProcessMenu);
            app.SmoothMenu.MenuSelectedFcn = createCallbackFcn(app, @SmoothMenuSelected, true);
            app.SmoothMenu.Text = 'Smooth';
            app.SharperMenu = uimenu(app.ProcessMenu);
            app.SharperMenu.MenuSelectedFcn = createCallbackFcn(app, @SharperMenuSelected, true);
            app.SharperMenu.Text = 'Sharper';
            app.WatermarkMenu = uimenu(app.UIFigure);
            app.WatermarkMenu.MenuSelectedFcn = createCallbackFcn(app, @WatermarkMenuSelected, true);
            app.WatermarkMenu.Text = 'Watermark';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Label = uilabel(app.LeftPanel);
            app.Button = uibutton(app.LeftPanel, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = 'pause.jpg';
            app.Button.Text = '';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.Limits = [0 10];
            app.Slider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.DeleteButton = uibutton(app.LeftPanel, 'state');
            app.DeleteButton.ValueChangedFcn = createCallbackFcn(app, @DeleteButtonValueChanged, true);
            app.DeleteButton.Text = 'Delete';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image_2 = uiimage(app.RightPanel);
            app.Label2 = uilabel(app.RightPanel);
            app.Label2.Text = 'Label2';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.Text = 'Button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW2_M11112024

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end